classdef Series_settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SeriesUIFigure                 matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        OKButton                       matlab.ui.control.Button
        maxsizeofclusterinnmSpinnerLabel  matlab.ui.control.Label
        SampletwoshortnameEditFieldLabel  matlab.ui.control.Label
        SampleoneshortnameEditFieldLabel  matlab.ui.control.Label
        EditShortName2                 matlab.ui.control.EditField
        EditShortName1                 matlab.ui.control.EditField
        SampleonedirectoryEditFieldLabel_2  matlab.ui.control.Label
        EditDir2                       matlab.ui.control.EditField
        SizeofresampleSpinnerLabel     matlab.ui.control.Label
        SpinnerResampleSize            matlab.ui.control.Spinner
        NumberofresamplesSpinnerLabel  matlab.ui.control.Label
        SpinnerResampleNumber          matlab.ui.control.Spinner
        StepsizeforgrowingclustersizeSpinnerLabel  matlab.ui.control.Label
        SpinnerStepClustering          matlab.ui.control.Spinner
        SampleonedirectoryEditFieldLabel  matlab.ui.control.Label
        SpinnerRmax                    matlab.ui.control.Spinner
        EditDir1                       matlab.ui.control.EditField
        CancelButton                   matlab.ui.control.Button
        ButtonSelectDir1               matlab.ui.control.Button
        ButtonSelectDir2               matlab.ui.control.Button
    end

    
    properties (Access = public)
        Success % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if isempty(app.EditDir1.Value)
                uialert(app.SeriesUIFigure,"Sample one directory not selected!","Error")
                return
            end
            if isempty(app.EditDir2.Value)
                uialert(app.SeriesUIFigure,"Sample two directory not selected!","Error")
                return
            end
            if isempty(app.EditShortName1.Value)
                uialert(app.SeriesUIFigure,"Shortname of sample one not selected!","Error")
                return
            end
            if isempty(app.EditShortName2.Value)
                uialert(app.SeriesUIFigure,"Shortname of sample two not selected!","Error")
                return
            end
            app.Success = true;
            uiresume(app.SeriesUIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.Success = false;
            app.SeriesUIFigure.Visible = 'off';
            uiresume(app.SeriesUIFigure);
        end

        % Close request function: SeriesUIFigure
        function SeriesUIFigureCloseRequest(app, event)
            app.Success = false;
            app.SeriesUIFigure.Visible = 'off';
            uiresume(app.SeriesUIFigure);
        end

        % Button pushed function: ButtonSelectDir1
        function ButtonSelectDir1Pushed(app, event)
            dir = uigetdir('C:\');
            figure(app.SeriesUIFigure);
            if dir ~= 0
                app.EditDir1.Value = dir;
            end
        end

        % Button pushed function: ButtonSelectDir2
        function ButtonSelectDir2Pushed(app, event)
            dir = uigetdir('C:\');
            figure(app.SeriesUIFigure);
            if dir ~= 0
                app.EditDir2.Value = dir;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SeriesUIFigure = uifigure('Visible', 'off');
            app.SeriesUIFigure.Name = '2CALM Series Analysis';
            app.SeriesUIFigure.CloseRequestFcn = createCallbackFcn(app, @SeriesUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.SeriesUIFigure);
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.maxsizeofclusterinnmSpinnerLabel = uilabel(app.GridLayout);
            app.maxsizeofclusterinnmSpinnerLabel.Text = 'Max size of cluster:';
            app.SampletwoshortnameEditFieldLabel = uilabel(app.GridLayout);
            app.SampletwoshortnameEditFieldLabel.Text = 'Sample two short name:';
            app.SampleoneshortnameEditFieldLabel = uilabel(app.GridLayout);
            app.SampleoneshortnameEditFieldLabel.Text = 'Sample one short name:';
            app.EditShortName2 = uieditfield(app.GridLayout, 'text');
            app.EditShortName1 = uieditfield(app.GridLayout, 'text');
            app.SampleonedirectoryEditFieldLabel_2 = uilabel(app.GridLayout);
            app.SampleonedirectoryEditFieldLabel_2.Text = 'Sample two directory:';
            app.EditDir2 = uieditfield(app.GridLayout, 'text');
            app.EditDir2.Editable = 'off';
            app.SizeofresampleSpinnerLabel = uilabel(app.GridLayout);
            app.SizeofresampleSpinnerLabel.Text = 'Size of resample:';
            app.SpinnerResampleSize = uispinner(app.GridLayout);
            app.SpinnerResampleSize.Limits = [1 Inf];
            app.SpinnerResampleSize.Value = 1000;
            app.NumberofresamplesSpinnerLabel = uilabel(app.GridLayout);
            app.NumberofresamplesSpinnerLabel.Text = 'Number of resamples:';
            app.SpinnerResampleNumber = uispinner(app.GridLayout);
            app.SpinnerResampleNumber.Limits = [1 Inf];
            app.SpinnerResampleNumber.Value = 50;
            app.StepsizeforgrowingclustersizeSpinnerLabel = uilabel(app.GridLayout);
            app.StepsizeforgrowingclustersizeSpinnerLabel.Text = 'Step size for growing cluster size:';
            app.SpinnerStepClustering = uispinner(app.GridLayout);
            app.SpinnerStepClustering.Limits = [1 1000];
            app.SpinnerStepClustering.ValueDisplayFormat = '%11.4g nm';
            app.SpinnerStepClustering.Value = 10;
            app.SampleonedirectoryEditFieldLabel = uilabel(app.GridLayout);
            app.SampleonedirectoryEditFieldLabel.Text = 'Sample one directory:';
            app.SpinnerRmax = uispinner(app.GridLayout);
            app.SpinnerRmax.Limits = [1 1000000];
            app.SpinnerRmax.ValueDisplayFormat = '%11.4g nm';
            app.SpinnerRmax.Value = 300;
            app.EditDir1 = uieditfield(app.GridLayout, 'text');
            app.EditDir1.Editable = 'off';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ButtonSelectDir1 = uibutton(app.GridLayout, 'push');
            app.ButtonSelectDir1.ButtonPushedFcn = createCallbackFcn(app, @ButtonSelectDir1Pushed, true);
            app.ButtonSelectDir1.Text = '...';
            app.ButtonSelectDir2 = uibutton(app.GridLayout, 'push');
            app.ButtonSelectDir2.ButtonPushedFcn = createCallbackFcn(app, @ButtonSelectDir2Pushed, true);
            app.ButtonSelectDir2.Text = '...';
            % Show the figure after all components are created
            app.SeriesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Series_settings

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SeriesUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SeriesUIFigure)
        end
    end
end